classdef therion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        Tree                      matlab.ui.container.Tree
        Node                      matlab.ui.container.TreeNode
        Node_2                    matlab.ui.container.TreeNode
        AddButton                 matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        TabGroup                  matlab.ui.container.TabGroup
        svxTab                    matlab.ui.container.Tab
        th2Tab                    matlab.ui.container.Tab
        TabGroup2                 matlab.ui.container.TabGroup
        SurveyTab                 matlab.ui.container.Tab
        SurveyNameEditFieldLabel  matlab.ui.control.Label
        SurveyNameEditField       matlab.ui.control.EditField
        ConfigTab                 matlab.ui.container.Tab
        thTab                     matlab.ui.container.Tab
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        NodeData = struct() % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNode = app.Tree.SelectedNodes;
            
            
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            selectedNode = app.Tree.SelectedNodes;
            newNode = uitreenode(selectedNode);
            newNode.Text = "New Survey";
            newNode.NodeData = struct('config',{},'equates',{},'joins',{},'maps',{},'svx',{},'th2',{});
            app.Tree.SelectedNodes = newNode; 
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Tree = uitree(app.LeftPanel);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node_2 = uitreenode(app.Node);
            app.Node_2.Text = 'Node';
            app.AddButton = uibutton(app.LeftPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.svxTab = uitab(app.TabGroup);
            app.svxTab.Title = 'svx';
            app.th2Tab = uitab(app.TabGroup);
            app.th2Tab.Title = 'th2';
            app.TabGroup2 = uitabgroup(app.RightPanel);
            app.SurveyTab = uitab(app.TabGroup2);
            app.SurveyTab.Title = 'Survey';
            app.SurveyNameEditFieldLabel = uilabel(app.SurveyTab);
            app.SurveyNameEditFieldLabel.Text = 'Survey Name';
            app.SurveyNameEditField = uieditfield(app.SurveyTab, 'text');
            app.ConfigTab = uitab(app.TabGroup2);
            app.ConfigTab.Title = 'Config';
            app.thTab = uitab(app.TabGroup2);
            app.thTab.Title = 'th';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = therion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end